classdef SIM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        start_button                   matlab.ui.container.toolbar.PushTool
        visual_button                  matlab.ui.container.toolbar.ToggleTool
        reset_button                   matlab.ui.container.toolbar.PushTool
        TabGroup                       matlab.ui.container.TabGroup
        config_tab                     matlab.ui.container.Tab
        status                         matlab.ui.control.EditField
        StatusEditFieldLabel           matlab.ui.control.Label
        Exoesqueletode6GdLLabel        matlab.ui.control.Label
        SimuladorDinmicoyControlLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        sLabel_2                       matlab.ui.control.Label
        ParmetrosdeajusteLabel         matlab.ui.control.Label
        TiempodeSimulacinEditField     matlab.ui.control.NumericEditField
        TiempodeSimulacinEditFieldLabel  matlab.ui.control.Label
        close_loop_panel               matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        ts_target                      matlab.ui.control.NumericEditField
        t_sLabel                       matlab.ui.control.Label
        q_des                          matlab.ui.control.EditField
        matbfdotqdLabel                matlab.ui.control.Label
        open_loop_panel                matlab.ui.container.Panel
        NsmLabel                       matlab.ui.control.Label
        NmLabel                        matlab.ui.control.Label
        b_art                          matlab.ui.control.NumericEditField
        bLabel                         matlab.ui.control.Label
        torque_input                   matlab.ui.control.EditField
        mathbftauLabel                 matlab.ui.control.Label
        config_select                  matlab.ui.control.DropDown
        ConfiguracindeSistemaDropDownLabel  matlab.ui.control.Label
        result_tab                     matlab.ui.container.Tab
        resultados                     matlab.ui.container.TabGroup
        var_estado                     matlab.ui.container.Tab
        EjecutealgunasimulacinparavisualizarlosresultadosLabel_2  matlab.ui.control.Label
        energia_sys                    matlab.ui.container.Tab
        EjecutealgunasimulacinparavisualizarlosresultadosLabel  matlab.ui.control.Label
        respuesta                      matlab.ui.container.Tab
        hide_plots2                    matlab.ui.container.Panel
        DisponiblesloenconfiguracindelazocerradoLabel  matlab.ui.control.Label
        energia_control                matlab.ui.container.Tab
        hide_plots1                    matlab.ui.container.Panel
        DisponiblesloenconfiguracindelazocerradoLabel_2  matlab.ui.control.Label
        report_tab                     matlab.ui.container.Tab
        table_report_joint             matlab.ui.control.Table
        log_info                       matlab.ui.control.TextArea
        ResumenLabel                   matlab.ui.control.Label
        text_info                      matlab.ui.control.Label
        table_report_general           matlab.ui.control.Table
    end

    
    properties (Access = private)
        control   % Switch-case para selecionar el tipo de sistema y el control
        ts        % Tiempo de establecimiento [close-loop]
        torque    % Torque para cada articulación [open-loop]
        b_ar      % Factor de disipación para articulaciones. 
        ref       % q deseada (vector) [close-loop]
        b_ar_temp % Factor de disipación - temp - 
        t_sim     % Tiempo de simulación
        disp_enable % Habilita al visualizador
        time_used % Tiempo que tardó la simulación. 
        type_system % Indica que tipo de sistema se simuló
        general % Valores importantes de control
        report_general % Tabla de reporte [General]
        report_joint % Tabla de reporte [Por articulación]
        error_ss % Vectores de error en estado estacionario
        energy_control % Energía acumulada de cada torque aplicado
        tau_aplicado % Torque aplicado a cada articulación
        tau_mean % Torques aplicados por articulación (promedio)
        tau_max  % Torques máximos por articulación (máximos)

        % Variables para plotear
        colores % Estilizado
        q
        dq
        time
        K
        U
        E
        e_control 
        error
        
        
    end
    
    methods (Access = private)
        
        function send_data_simulink(app)
            assignin('base','control',[0 app.control]);
            assignin('base','ts',[0 app.ts]);
            assignin('base','torque',[0 app.torque]);
            assignin('base','ref',[0 app.ref]);
            assignin('base','b_ar',[0 app.b_ar]);
            assignin('base','disp_enable',[0 app.disp_enable]);
        end
        
        function reset_sim_values(app)
            app.t_sim = app.TiempodeSimulacinEditField.Value;
            app.control = 100;
            app.ts = 0.5;
            app.torque =  [0 0 0 0 0 0];
            app.ref =  [0 0 0 0 0 0];
            app.b_ar = 0.01;
            app.b_ar_temp = app.b_ar;
            app.disp_enable = 0;
            app.type_system = 'Lazo Abierto (Conservativo)';
            app.general = zeros(2,3); 
            app.status.Value = 'En espera de iniciar simulación ...';
        end
        
        function v_num = string2vec(~,v_string)
            n_space = length(strfind(v_string,' '));
            assert(isequal(n_space,5),'Error de dimensión :: Faltan parámetros por asignar')
            % Ni idea con el warning :s Si se le cambia a Double marca
            % error.
            v_num = str2num(v_string);
            assert(isa(v_num,'double'),'Error, no es un número.')
        end
        
        function sistema_conservativo(app)
            app.b_art.Value = 0;
            app.b_ar = 0;
            app.control = 100;
            app.torque = [0 0 0 0 0 0];
            app.torque_input.Enable = 'off';
            app.b_art.Enable = 'off';

            enable_open_loop_panel(app);
        end
        
        function sistema_no_conservativo(app)
            app.torque_input.Enable = 'on';
            app.b_art.Enable = 'on';
            app.b_ar = app.b_ar_temp;
            app.b_art.Value = app.b_ar;

            enable_open_loop_panel(app);
        end
        
        function sistema_control(app,selected)
            app.torque_input.Enable = 'on';
            app.b_art.Enable = 'on';
            app.b_ar = app.b_ar_temp;
            app.b_art.Value = app.b_ar;

            enable_control_panel(app);
            
            switch selected
                case 'Control PD'
                    app.control = 5;
                case 'Control PD+g (Compensación Estática)'
                    app.control = 4;
                case 'Control PD+g (Compensación Dinámica)'
                    app.control = 3;
                case 'Control PID (LGR)'
                    app.control = 2;
                case 'Control PID (Sintonización)'
                    app.control = 1;
            end
        end
        
        function enable_control_panel(app)
            app.open_loop_panel.Enable = 'off';
            app.open_loop_panel.Visible = 'off';
            app.close_loop_panel.Visible = 'on';
            app.close_loop_panel.Enable = 'on';
        end
        
        function enable_open_loop_panel(app)
            app.open_loop_panel.Enable = 'on';
            app.open_loop_panel.Visible = 'on';
            app.close_loop_panel.Visible = 'off';
            app.close_loop_panel.Enable = 'off';
            results_control(app,'off');
        end
        
        function plot_variables_estado(app)
            set_graphics_color(app);
            p = uipanel(app.var_estado,'Position',[0,1,518,520],'AutoResizeChildren','off');
            fig1 = subplot(2,1,1,'Parent',p);
            fig2 = subplot(2,1,2,'Parent',p);
            handles.fig1 = fig1;
            handles.fig2 = fig2;
            hold(handles.fig1,'on')
            hold(handles.fig2,'on')
            for i = 1:6
                plot(handles.fig1,app.time, app.q(i,:),'Color',app.colores(i,:))
                plot(handles.fig2,app.time, app.dq(i,:),'Color',app.colores(i,:))
            end
            hold(handles.fig1,'off')
            hold(handles.fig2,'off')
            xlabel(handles.fig1,'Tiempo: [s]','Interpreter','latex')
            xlabel(handles.fig2,'Tiempo: [s]','Interpreter','latex')
            ylabel(handles.fig1,'$\mathbf{q}(t)$: [rad]','Interpreter','latex')
            ylabel(handles.fig2,'$\mathbf{\dot{q}}(t)$: [rad/s]','Interpreter','latex')
            legend(handles.fig1, {'$q_1$','$q_2$','$q_3$','$q_4$','$q_5$','$q_6$'},'Interpreter','latex');
            legend(handles.fig2, {'$\dot{q}_1$','$\dot{q}_2$','$\dot{q}_3$','$\dot{q}_4$','$\dot{q}_5$','$\dot{q}_6$'},'Interpreter','latex');
            title(handles.fig1,'Coordenadas Generalizadas','Interpreter','latex')
            title(handles.fig2,'Velocidades Generalizadas','Interpreter','latex')
            grid(handles.fig1,'on')
            grid(handles.fig2,'on')
        end

        function plot_energias(app)
            p = uipanel(app.energia_sys,'Position',[0,1,518,520],'AutoResizeChildren','off');
            fig1 = subplot(1,1,1,'Parent',p);
            handles.fig1 = fig1;
            plot(handles.fig1, app.time, app.K, app.time, app.U, app.time, app.E)
            xlabel(handles.fig1,'Tiempo: [s]','Interpreter','latex')
            ylabel(handles.fig1,'$K(t)$, $U(t)$, $E(t)$: [J]','Interpreter','latex')
            legend(handles.fig1, {'Energ\''ia Cin\''etica $K$','Energ\''ia Potencial $U$','Energ\''ia Mec\''anica $E$'},'Interpreter','latex');
            grid(handles.fig1,'on')
            title(handles.fig1,'Comportamiento energ\''etico','Interpreter','latex')
        end

        function plot_respuesta(app)
            set_graphics_color(app);
            p = uipanel(app.respuesta,'Position',[0,1,518,520],'AutoResizeChildren','off');
            fig1 = subplot(2,1,1,'Parent',p);
            fig2 = subplot(2,1,2,'Parent',p);
            handles.fig1 = fig1;
            handles.fig2 = fig2;
            if app.control ~= 100
                hold(handles.fig1,'on')
                for i = 1:6
                    plot(handles.fig1, app.time, app.q(i,:),'Color', app.colores(i,:))                    
                end                
                for i = 1:6
                    plot(handles.fig1, app.time, 0*app.time+app.ref(i),'--','Color', app.colores(i,:))
                end
                legend(handles.fig1, {'$q_1$','$q_2$','$q_3$','$q_4$','$q_5$','$q_6$','','','','','',''},'Interpreter','latex');
                hold(handles.fig1,'off')
                hold(handles.fig2,'on')
                for i = 1:6
                    plot(handles.fig2,app.time, app.error(:,i), 'Color',app.colores(i,:));
                end
                hold(handles.fig2,'off')
                
                legend(handles.fig2, {'$e_1$','$e_2$','$e_3$','$e_4$','$e_5$','$e_6$'},'Interpreter','latex');
            end
            xlabel(handles.fig1,'Tiempo: [s]','Interpreter','latex')
            xlabel(handles.fig2,'Tiempo: [s]','Interpreter','latex')
            ylabel(handles.fig1,'$\mathbf{q}(t)$, $\mathbf{q}^d(t)$: [rad]','Interpreter','latex')
            ylabel(handles.fig2,'$\mathbf{e}(t)$','Interpreter','latex')
            grid(handles.fig1,'on')
            grid(handles.fig2,'on')
            title(handles.fig1,'Respuesta del sistema','Interpreter','latex')
            title(handles.fig2,'Error en la respuesta','Interpreter','latex')
        end

        function plot_energia_control(app)
            set_graphics_color(app);
            p = uipanel(app.energia_control,'Position',[0,1,518,520],'AutoResizeChildren','off');
            fig1 = subplot(2,1,1,'Parent',p);
            fig2 = subplot(2,1,2,'Parent',p);
            handles.fig1 = fig1;
            handles.fig2 = fig2;
            hold(handles.fig1,'on')
            hold(handles.fig2,'on')
            if app.control ~= 100
                for i = 1:6
                    plot(handles.fig1, app.time, app.e_control(i,:),'Color',app.colores(i,:));
                    plot(handles.fig2, app.time, app.tau_aplicado(i,:), 'Color',app.colores(i,:));
                end

                legend(handles.fig1, {'$E_{\tau_1}$','$E_{\tau_2}$','$E_{\tau_3}$','$E_{\tau_4}$','$E_{\tau_5}$','$E_{\tau_6}$'},'Interpreter','latex');
                legend(handles.fig2, {'$\tau_1$','$\tau_2$','$\tau_3$','$\tau_4$','$\tau_5$','$\tau_6$'},'Interpreter','latex');
            end
            hold(handles.fig1,'off')
            hold(handles.fig2,'off')
            xlabel(handles.fig1,'Tiempo: [s]','Interpreter','latex')
            xlabel(handles.fig2,'Tiempo: [s]','Interpreter','latex')
            ylabel(handles.fig1,'$E_{\mathbf{\tau}}(t)$: [J]','Interpreter','latex')
            ylabel(handles.fig2,'$\mathbf{\tau}(t)$: [Nm]','Interpreter','latex')
            title(handles.fig1,'Energ\''ia aplicada a cada articulaci\''on','Interpreter','latex')
            title(handles.fig2,'Torques aplicados a cada articulaci\''on','Interpreter','latex')
            grid(handles.fig1,'on')
            grid(handles.fig2,'on')
        end
        
        function unpack_sim_values(app,sim_out)
            app.time = sim_out.position.Time;
            n = size(app.time,1);
            app.q = reshape(sim_out.position.Data(:,1,:),6,n);
            app.dq = reshape(sim_out.velocity.Data(:,1,:),6,n);
            app.K = sim_out.kinetic_energy.Data;
            app.U = sim_out.potential_energy.Data;
            app.E = sim_out.mechanic_energy.Data;
            app.tau_aplicado =  abs(reshape(sim_out.torque_control.Data(:,1,:),6,n));
            app.e_control = abs(reshape(sim_out.e_control.Data(:,1,:),6,n));
            app.error = sim_out.error.Data;
        end
        
        function results_control(app,status)
            app.respuesta.HandleVisibility = status;
            app.energia_control.HandleVisibility = status;
            app.table_report_joint.Visible = status;
        end
        
        function set_to_simulation(app,status)
            app.start_button.Enable = status;
            %app.visual_button = status;
        end
        
        function print_log_message(app,msg)
            app.log_info.Value = msg;            
        end
        
        function msg = make_msg_log(app)
            rp = 100;
            ss = repmat(' ',1,rp);

            pr = ['--- PARÁMETROS DE SIMULACIÓN ---'];
            x1 = ['Configuración analizada: ', app.type_system];
            x2 = ['Factor de disipación utilizado: ', num2str(app.b_ar), '  [Ns/m]'];
            x3 = ['Tiempo simulado: ', num2str(app.t_sim), '  [s]'];
            x4 = ['Tiempo que tardó en simular: ', num2str(app.time_used), '  [s]'];

            msg = {pr,ss,x1,x2,x3,x4,};
        end
        
        function set_general_data_OL(app)
            app.report_general(1,:) = {"Energía Mecánica Sistema", "[J]", min(app.E),mean(app.E),max(app.E)};
            app.report_general(2,:) = {"Energía Potencial Sistema", "[J]", min(app.U),mean(app.U),max(app.U)};
            app.report_general(3,:) = {"Energía Cinética Sistema", "[J]", min(app.K),mean(app.K),max(app.K)};
        end
        
        function init_report_tables(app)
            app.table_report_general.Visible = 'on';
            app.table_report_joint.Visible = 'on';
            app.log_info.Visible = 'on';

            app.report_general = table;
            app.report_joint = table;
        end
        
        function update_table_report(app)
            app.text_info.Visible = 'off';
            app.table_report_general.Data = app.report_general;
            app.table_report_joint.Data = app.report_joint;
        end
        
        function get_error_ss(app)
            app.error_ss = app.error(end,:);
        end
        
        function set_general_data_CL(app)
            get_error_ss(app);
            get_control_values(app);

            app.report_general(4,:) = {"Error estado estacionario", "[rad]",min(app.error_ss),mean(app.error_ss),max(app.error_ss)};
            app.report_general(5,:) = {"Energía general aplicada", "[J]",min(app.energy_control),mean(app.energy_control),max(app.energy_control)};
        end
        
        function get_control_values(app)

            temp1 = zeros(1,6);
            temp2 = zeros(1,6);
            temp3 = zeros(1,6);
            for i = 1:6
               temp1(i) = max(app.e_control(i,:));
               temp2(i) = mean(app.tau_aplicado(i,:));
               temp3(i) = max(app.tau_aplicado(i,:));
            end
            app.energy_control = 1000*temp1; % Conversión a mJ
            app.tau_mean = temp2;
            app.tau_max = temp3;
        end
        
        function set_joints_data_CL(app)
            app.report_joint(1,:) = table("q_d", "[rad]",app.ref);
            app.report_joint(2,:) = table("Error estado estacionario","[rad]", app.error_ss);
            app.report_joint(3,:) = table("Torque promedio", "[Nm]", app.tau_mean);
            app.report_joint(4,:) = table("Torque máximo", "[Nm]", app.tau_max);
            app.report_joint(5,:) = table("Energía máxima aplicada", "[mJ]", app.energy_control); 
        end
        
        function hide_control_plots(app,status)
            app.hide_plots1.Visible = status;
            app.hide_plots2.Visible = status;
        end
        
        function set_graphics_color(app)
            app.colores(1,:) = [0, 0.4470, 0.7410];
            app.colores(2,:) = [0.8500, 0.3250, 0.0980];
            app.colores(3,:) = [0.9290, 0.6940, 0.1250];
            app.colores(4,:) = [0.4940, 0.1840, 0.5560];
            app.colores(5,:) = [0.4660, 0.6740, 0.1880];
            app.colores(6,:) = [0.6350, 0.0780, 0.1840];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup_GUI(app)
            reset_sim_values(app);
            send_data_simulink(app);
            sistema_conservativo(app);
            enable_open_loop_panel(app);
            results_control(app,'off');
            set_to_simulation(app,'on');
            
        end

        % Value changed function: torque_input
        function torque_inputValueChanged(app, event)
            value = app.torque_input.Value;
            app.torque = string2vec(app,value);
        end

        % Value changed function: q_des
        function q_desValueChanged(app, event)
            value = app.q_des.Value;
            app.ref = deg2rad(string2vec(app,value));
        end

        % Value changed function: config_select
        function config_selectValueChanged(app, event)
            value = app.config_select.Value;

            app.type_system = value;

            switch value
                case 'Lazo Abierto (Conservativo)'
                    app.control = 100;
                    sistema_conservativo(app);

                case 'Lazo Abierto (No conservativo)'
                    app.control = 100;
                    sistema_no_conservativo(app);

                otherwise
                    sistema_control(app,value);
            end
        end

        % Value changed function: ts_target
        function ts_targetValueChanged(app, event)
            value = app.ts_target.Value;
            assert(isa(value,'double'),'Verifique el valor del tiempo de establecimiento');
            app.ts = value;
        end

        % Clicked callback: start_button
        function main_simulation(app, event)
            clc
            
            tic
            app.status.Value = 'Simulando, por favor espere';
            set_to_simulation(app,'off');
            send_data_simulink(app);
            
            simout = sim('SIM_EXO_DAL.slx','StopTime',num2str(app.t_sim));
            
            app.time_used = toc;
            
            unpack_sim_values(app,simout);


            init_report_tables(app);
            set_general_data_OL(app);

            results_control(app,'off');
            hide_control_plots(app,'on');
            
            plot_respuesta(app);
            plot_energia_control(app);

            if app.control ~= 100
                app.b_ar = 0.004;
                app.b_art.Value = app.b_ar;
                results_control(app,'on');
                set_general_data_CL(app);
                set_joints_data_CL(app);
                hide_control_plots(app,'off');
            end

            set_to_simulation(app,'on')

            msg = make_msg_log(app);

            print_log_message(app,msg);

            plot_variables_estado(app);
            plot_energias(app);
            update_table_report(app);
            app.status.Value = 'Simulado, verifique resultados';
        end

        % Value changed function: b_art
        function b_artValueChanged(app, event)
            value = app.b_art.Value;
            assert(isa(value,'double'),'Verifique el valor del factor de disipación');
            app.b_ar_temp = value;
            app.b_ar = value;
        end

        % Value changed function: TiempodeSimulacinEditField
        function TiempodeSimulacinEditFieldValueChanged(app, event)
            value = app.TiempodeSimulacinEditField.Value;
            app.t_sim = value;
        end

        % On callback: visual_button
        function visual_buttonOn(app, event)
            app.disp_enable = 1;
        end

        % Off callback: visual_button
        function visual_buttonOff(app, event)
            app.disp_enable = 0;
        end

        % Clicked callback: reset_button
        function reset_buttonClicked(app, event)
            reset_sim_values(app);
        end

        % Cell edit callback: table_report_general
        function table_report_generalCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.start_button = uipushtool(app.Toolbar);
            app.start_button.Tooltip = {'Iniciar Simulación'};
            app.start_button.ClickedCallback = createCallbackFcn(app, @main_simulation, true);
            app.start_button.Icon = 'icons8-play.png';
            app.start_button.Separator = 'on';
            app.visual_button = uitoggletool(app.Toolbar);
            app.visual_button.Tooltip = {'Activar/Desactivar Visualizador'};
            app.visual_button.Icon = 'icons8-robot.png';
            app.visual_button.Separator = 'on';
            app.visual_button.OffCallback = createCallbackFcn(app, @visual_buttonOff, true);
            app.visual_button.OnCallback = createCallbackFcn(app, @visual_buttonOn, true);
            app.reset_button = uipushtool(app.Toolbar);
            app.reset_button.ClickedCallback = createCallbackFcn(app, @reset_buttonClicked, true);
            app.reset_button.Icon = 'icons8-settings.png';
            app.reset_button.Separator = 'on';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.config_tab = uitab(app.TabGroup);
            app.config_tab.Title = 'Configuración';
            app.ConfiguracindeSistemaDropDownLabel = uilabel(app.config_tab);
            app.ConfiguracindeSistemaDropDownLabel.Text = 'Configuración de Sistema';
            app.config_select = uidropdown(app.config_tab);
            app.config_select.Items = {'Lazo Abierto (Conservativo)', 'Lazo Abierto (No conservativo)', 'Control PD', 'Control PD+g (Compensación Estática)', 'Control PD+g (Compensación Dinámica)', 'Control PID (LGR)', 'Control PID (Sintonización)'};
            app.config_select.ValueChangedFcn = createCallbackFcn(app, @config_selectValueChanged, true);
            app.config_select.Value = 'Lazo Abierto (Conservativo)';
            app.open_loop_panel = uipanel(app.config_tab);
            app.mathbftauLabel = uilabel(app.open_loop_panel);
            app.mathbftauLabel.Interpreter = 'latex';
            app.mathbftauLabel.Text = '$\mathbf{\tau}$';
            app.torque_input = uieditfield(app.open_loop_panel, 'text');
            app.torque_input.ValueChangedFcn = createCallbackFcn(app, @torque_inputValueChanged, true);
            app.torque_input.Value = '0 0 0 0 0 0';
            app.bLabel = uilabel(app.open_loop_panel);
            app.bLabel.Interpreter = 'latex';
            app.bLabel.Text = '$b_{art}$';
            app.b_art = uieditfield(app.open_loop_panel, 'numeric');
            app.b_art.Limits = [0 1];
            app.b_art.ValueChangedFcn = createCallbackFcn(app, @b_artValueChanged, true);
            app.NmLabel = uilabel(app.open_loop_panel);
            app.NmLabel.Interpreter = 'latex';
            app.NmLabel.Text = '[N m]';
            app.NsmLabel = uilabel(app.open_loop_panel);
            app.NsmLabel.Interpreter = 'latex';
            app.NsmLabel.Text = '[Ns/m]';
            app.close_loop_panel = uipanel(app.config_tab);
            app.close_loop_panel.Enable = 'off';
            app.matbfdotqdLabel = uilabel(app.close_loop_panel);
            app.matbfdotqdLabel.Interpreter = 'latex';
            app.matbfdotqdLabel.Text = '$\mathbf{q}^d$';
            app.q_des = uieditfield(app.close_loop_panel, 'text');
            app.q_des.ValueChangedFcn = createCallbackFcn(app, @q_desValueChanged, true);
            app.q_des.Value = '0 0 0 0 0 0';
            app.t_sLabel = uilabel(app.close_loop_panel);
            app.t_sLabel.Interpreter = 'latex';
            app.t_sLabel.Text = '$t_s$';
            app.ts_target = uieditfield(app.close_loop_panel, 'numeric');
            app.ts_target.Limits = [0.001 3];
            app.ts_target.ValueChangedFcn = createCallbackFcn(app, @ts_targetValueChanged, true);
            app.ts_target.Value = 0.5;
            app.sLabel = uilabel(app.close_loop_panel);
            app.sLabel.Interpreter = 'latex';
            app.sLabel.Text = '[s]';
            app.Label = uilabel(app.close_loop_panel);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '[°]';
            app.TiempodeSimulacinEditFieldLabel = uilabel(app.config_tab);
            app.TiempodeSimulacinEditFieldLabel.Text = 'Tiempo de Simulación';
            app.TiempodeSimulacinEditField = uieditfield(app.config_tab, 'numeric');
            app.TiempodeSimulacinEditField.Limits = [0 60];
            app.TiempodeSimulacinEditField.ValueChangedFcn = createCallbackFcn(app, @TiempodeSimulacinEditFieldValueChanged, true);
            app.TiempodeSimulacinEditField.Value = 20;
            app.ParmetrosdeajusteLabel = uilabel(app.config_tab);
            app.ParmetrosdeajusteLabel.Text = 'Parámetros de ajuste';
            app.sLabel_2 = uilabel(app.config_tab);
            app.sLabel_2.Interpreter = 'latex';
            app.sLabel_2.Text = '[s]';
            app.Image = uiimage(app.config_tab);
            app.Image.ImageSource = 'ryma_icon.png';
            app.SimuladorDinmicoyControlLabel = uilabel(app.config_tab);
            app.SimuladorDinmicoyControlLabel.Text = 'Simulador Dinámico y Control';
            app.Exoesqueletode6GdLLabel = uilabel(app.config_tab);
            app.Exoesqueletode6GdLLabel.Text = 'Exoesqueleto de 6 GdL';
            app.StatusEditFieldLabel = uilabel(app.config_tab);
            app.StatusEditFieldLabel.Text = 'Status';
            app.status = uieditfield(app.config_tab, 'text');
            app.status.Editable = 'off';
            app.result_tab = uitab(app.TabGroup);
            app.result_tab.Title = 'Gráficas de resultados';
            app.resultados = uitabgroup(app.result_tab);
            app.var_estado = uitab(app.resultados);
            app.var_estado.Title = 'Variables de Estado';
            app.EjecutealgunasimulacinparavisualizarlosresultadosLabel_2 = uilabel(app.var_estado);
            app.EjecutealgunasimulacinparavisualizarlosresultadosLabel_2.Text = 'Ejecute alguna simulación para visualizar los resultados';
            app.energia_sys = uitab(app.resultados);
            app.energia_sys.Title = 'Energía Sistema';
            app.EjecutealgunasimulacinparavisualizarlosresultadosLabel = uilabel(app.energia_sys);
            app.EjecutealgunasimulacinparavisualizarlosresultadosLabel.Text = 'Ejecute alguna simulación para visualizar los resultados';
            app.respuesta = uitab(app.resultados);
            app.respuesta.Title = 'Respuesta';
            app.hide_plots2 = uipanel(app.respuesta);
            app.DisponiblesloenconfiguracindelazocerradoLabel = uilabel(app.hide_plots2);
            app.DisponiblesloenconfiguracindelazocerradoLabel.Text = 'Disponible sólo en configuración de lazo cerrado';
            app.energia_control = uitab(app.resultados);
            app.energia_control.Title = 'Energía Control';
            app.hide_plots1 = uipanel(app.energia_control);
            app.DisponiblesloenconfiguracindelazocerradoLabel_2 = uilabel(app.hide_plots1);
            app.DisponiblesloenconfiguracindelazocerradoLabel_2.Text = 'Disponible sólo en configuración de lazo cerrado';
            app.report_tab = uitab(app.TabGroup);
            app.report_tab.Title = 'Reporte de Simulación';
            app.table_report_general = uitable(app.report_tab);
            app.table_report_general.ColumnName = {'Parámetro'; 'Dimensión'; 'Mínimo'; 'Promedio'; 'Máximo'};
            app.table_report_general.RowName = {};
            app.table_report_general.CellEditCallback = createCallbackFcn(app, @table_report_generalCellEdit, true);
            app.table_report_general.Visible = 'off';
            app.text_info = uilabel(app.report_tab);
            app.text_info.Text = 'Ejecute alguna simulación para visualizar los resultados';
            app.ResumenLabel = uilabel(app.report_tab);
            app.ResumenLabel.Visible = 'off';
            app.ResumenLabel.Text = 'Reporte de simulación';
            app.log_info = uitextarea(app.report_tab);
            app.log_info.Editable = 'off';
            app.log_info.Visible = 'off';
            app.table_report_joint = uitable(app.report_tab);
            app.table_report_joint.ColumnName = {'Parámetro'; 'Dimensión'; 'Por Articulación'};
            app.table_report_joint.RowName = {};
            app.table_report_joint.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup_GUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end